classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ModoDropDown              matlab.ui.control.DropDown
        ModoDropDownLabel         matlab.ui.control.Label
        GraficarDropDown          matlab.ui.control.DropDown
        GraficarDropDownLabel     matlab.ui.control.Label
        NumMasses                 matlab.ui.control.DropDown
        NmerodeMasasLabel         matlab.ui.control.Label
        CoordenadasDropDown       matlab.ui.control.DropDown
        CoordenadasDropDownLabel  matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        RecordButton              matlab.ui.control.Button
        ConnectButton             matlab.ui.control.Button
    end

    % APP PARA ADQUISICIÓN EN VIVO DESDE ROBOTAT Y GRAFICACIÓN X/Y/Z
    % - Conecta a Robotat
    % - Registra posiciones absolutas o relativas (respecto a GND)
    % - Grafica en tiempo real X, Y, Z para 1–4 masas
    % - Exporta CSV con las series registradas
    %
    % NOTA: Requiere Robotics Toolbox (transl/trotx/troty/trotz) y funciones
    % robotat_connect / robotat_get_pose disponibles en el path de MATLAB.

    %% ========= Propiedades internas (estado de la app) =========
    properties (Access = private)

        % Ventana emergente con gráficos en vivo
        liveFigure  matlab.ui.Figure
        liveLines       % matriz (numLive x 3) de líneas: X,Y,Z
        plotEnabled (1,1) logical = false;   % control maestro de graficación

    
        % --- Buffers crudos (preasignados) ---
        bufCap (1,1) double = 20000    % capacidad inicial
        wIdx   (1,1) double = 0        % índice de escritura (1-based)
        tBuf double = double.empty(0,1)

        % Masas (xyz) -> filas=ticks, columnas=1..4 (una por masa)
        rawX double = double.empty(0,4)
        rawY double = double.empty(0,4)
        rawZ double = double.empty(0,4)

        % GND por tick (sólo se usa en modo Relativas)
        rawGndP    double = double.empty(0,3)   % [x,y,z]
        rawGndEul  double = double.empty(0,3)   % [eulX,eulY,eulZ] en rad

        % Cacheo de parámetros (para no leer UI en cada tick)
        numLiveCached (1,1) double = 4
        useRelativeCached (1,1) logical = false


        % Configuración por defecto de cuántas masas graficar
        numLive (1,1) double = 4
        massIDs (1,4) double = [76 77 78 79];
        homeID = 80; % marcador de referencia para coordenadas relativas

        robotat % handle de conexión Robotat
        tmr % timer para muestreo periódico


        % Tiempo (vector y referencia de tic)
        t0
        tData = []


        % Históricos de cada masa (X, Y, Z)

        %Masa 1
        x1Data = []
        y1Data = []
        z1Data = []
    

        %Masa 2
        x2Data = []
        y2Data = []
        z2Data = []


        %Masa 3
        x3Data = []
        y3Data = []
        z3Data = []

        %Masa 4
        x4Data = []
        y4Data = []
        z4Data = []


        % Robustez de lectura / reconexión
        failCount   double = 0;   % fallos consecutivos de lectura
        maxFail     double = 5;   % umbral para intentar reconectar

        % Última carpeta usada para guardar CSV
        lastSaveFolder string = pwd;

    end


    %% ========= Lógica de adquisición y graficación =========
    methods (Access = private)

        function updateOnce(app)
            % LEE UNA MUESTRA, ACTUALIZA HISTÓRICOS Y GRÁFICOS
            % Se ejecuta periódicamente desde el timer.
            isReconnecting = false;
            % --- Chequeos básicos ---
            if isempty(app.robotat)
                app.failCount = app.failCount + 1; return;
            end
            doPlot = app.plotEnabled && ~isempty(app.liveFigure) && isvalid(app.liveFigure) && ~isempty(app.liveLines);
            n = app.numLiveCached;
            if isempty(n) || n<1, return; end
            if isempty(app.t0), app.t0 = tic; end
            t = toc(app.t0);

            try
                if app.useRelativeCached
                    % Lee masas + GND (una sola llamada)
                    ids  = [app.massIDs(1:n), app.homeID];
                    pose = robotat_get_pose(app.robotat, ids, 'eulxyz');  % [n+1 x 6]
                    % Extraer crudos
                    Pm = pose(1:n,1:3);       % masas xyz
                    pG = pose(n+1,1:3);       % GND pos
                    eG = pose(n+1,4:6);       % GND euler
                else
                    ids  = app.massIDs(1:n);
                    pose = robotat_get_pose(app.robotat, ids, 'eulxyz');  % [n x 6] (usamos 1:3)
                    Pm = pose(1:n,1:3);
                    pG = [NaN NaN NaN]; eG = [NaN NaN NaN];   % no usados en absolutas
                end

                % ====== MODO CRUDO: sólo almacenar ======
                app.wIdx = app.wIdx + 1;
                ensureRawCapacity(app, app.wIdx);
                app.tBuf(app.wIdx,1) = t;
                app.rawX(app.wIdx,1:n) = Pm(:,1).';
                app.rawY(app.wIdx,1:n) = Pm(:,2).';
                app.rawZ(app.wIdx,1:n) = Pm(:,3).';
                app.rawGndP(app.wIdx,:)   = pG;
                app.rawGndEul(app.wIdx,:) = eG;

                % ====== Si se grafica, procesa y pinta (como antes) ======
                if doPlot
                    
                    if app.useRelativeCached
                        % ==== Graficar relativas con HTMs (una sola evaluación en vivo) ====
                        % pG: [1x3], eG: [1x3] (rad). Pm: [n x 3] posiciones globales de las masas.
                        mkT = @(p) transl(p(1:3)) * trotx(p(4)) * troty(p(5)) * trotz(p(6));

                        % Transformación del GND en marco global
                        TG = mkT([pG, eG]);              % T_GND

                        n  = size(Pm, 1);
                        x  = zeros(1, n);
                        y  = zeros(1, n);
                        z  = zeros(1, n);

                        % Si tienes euler por masa en vivo, ponlos aquí; si no, se asumen 0.
                        % Ejemplo: Em = [ex ey ez] por fila (n x 3). Si no existe, descomenta la línea de ceros.
                        % Em = zeros(n, 3);

                        for k = 1:n
                            pk = Pm(k, :);                      % [x y z] global de la masa k

                            % Orientación de la masa (si no hay, usar [0 0 0])
                            % ek = Em(k, :);
                            ek = [0 0 0];

                            TMk  = mkT([pk, ek]);               % T_Mk global
                            Trel = TG \ TMk;                    % Trel = inv(T_GND) * T_Mk
                            pr   = transl(Trel);                % [x y z] en marco GND

                            % Convención de ejes solicitada
                            x(k) = -pr(1);
                            y(k) =  pr(2);
                            z(k) =  pr(3);
                        end
                    else
                        % ==== Graficar absolutas tal cual ====
                        x = Pm(:,1).';
                        y = Pm(:,2).';
                        z = Pm(:,3).';
                    end

                    % Actualiza históricos de plotting + líneas (tu bloque actual)
                    app.tData(end+1) = t;
                    for i=1:n
                        xi=x(i); yi=y(i); zi=z(i);
                        if ~(isfinite(xi)&&isfinite(yi)&&isfinite(zi)), continue; end
                        switch i
                            case 1, app.x1Data(end+1)=xi; app.y1Data(end+1)=yi; app.z1Data(end+1)=zi; xArr=app.x1Data; yArr=app.y1Data; zArr=app.z1Data;
                            case 2, app.x2Data(end+1)=xi; app.y2Data(end+1)=yi; app.z2Data(end+1)=zi; xArr=app.x2Data; yArr=app.y2Data; zArr=app.z2Data;
                            case 3, app.x3Data(end+1)=xi; app.y3Data(end+1)=yi; app.z3Data(end+1)=zi; xArr=app.x3Data; yArr=app.y3Data; zArr=app.z3Data;
                            case 4, app.x4Data(end+1)=xi; app.y4Data(end+1)=yi; app.z4Data(end+1)=zi; xArr=app.x4Data; yArr=app.y4Data; zArr=app.z4Data;
                        end
                        if size(app.liveLines,1)>=i && size(app.liveLines,2)>=3
                            if isvalid(app.liveLines(i,1)), set(app.liveLines(i,1),'XData',app.tData,'YData',xArr); end
                            if isvalid(app.liveLines(i,2)), set(app.liveLines(i,2),'XData',app.tData,'YData',yArr); end
                            if isvalid(app.liveLines(i,3)), set(app.liveLines(i,3),'XData',app.tData,'YData',zArr); end
                        end
                    end
                    % ventana deslizante + drawnow (igual que ya tienes)
                end

                app.failCount = 0;

                isReconnecting = false;

            catch ME
                % Log útil para depurar si algo falla
                disp('[updateOnce] Error atrapado:');
                disp(getReport(ME,'extended'));

                app.failCount = app.failCount + 1;
                if app.failCount >= app.maxFail && ~isReconnecting
                    isReconnecting = true;
                    try
                        disp('[Robotat] Reintentando conexión...');
                        app.robotat = robotat_connect();
                        app.failCount = 0;
                        isReconnecting = false;
                        disp('[Robotat] Reconectado.');
                    catch reconErr
                        isReconnecting = false;
                        disp(['[Robotat] No se pudo reconectar: ' reconErr.message]);
                    end
                end
            end
        end

        function createLiveWindow(app)
            app.liveFigure = uifigure('Name', sprintf('Vista en vivo: %d masas (X/Y/Z)', app.numLive), ...
                'Position', [200 200 900 220*app.numLive]);
            gl = uigridlayout(app.liveFigure, [app.numLive 3]);
            gl.RowHeight   = repmat({'1x'}, 1, app.numLive);
            gl.ColumnWidth = {'1x','1x','1x'};

            app.liveLines = gobjects(app.numLive, 3);

            coordLabels = {'X','Y','Z'};
            for i = 1:app.numLive
                for j = 1:3
                    ax = uiaxes(gl);
                    ln = plot(ax, nan, nan, 'LineWidth', 1.5);
                    title(ax, sprintf('Masa %d: %s vs t', i, coordLabels{j}));
                    xlabel(ax, 't [s]'); ylabel(ax, coordLabels{j}); grid(ax,'on');
                    app.liveLines(i,j) = ln;
                end
            end
        end

        function clearLiveGraphics(app)
            % Cierra y limpia todo lo de la ventana de gráficos
            if ~isempty(app.liveFigure) && all(isvalid(app.liveFigure))
                delete(app.liveFigure);
            end
            app.liveFigure = matlab.ui.Figure.empty;  % <-- en vez de []
            app.liveLines  = gobjects(0);             % <-- en vez de []
        end

      
        function initRawBuffers(app, cap)
            if nargin<2 || isempty(cap), cap = app.bufCap; end
            app.bufCap = cap; app.wIdx = 0;
            app.tBuf     = nan(cap,1,'single');
            app.rawX     = nan(cap,4,'single');
            app.rawY     = nan(cap,4,'single');
            app.rawZ     = nan(cap,4,'single');
            app.rawGndP  = nan(cap,3,'single');
            app.rawGndEul= nan(cap,3,'single');
        end

        function ensureRawCapacity(app, needIdx)
            if needIdx <= app.bufCap, return; end
            newCap = max(needIdx, app.bufCap*2);
            app.tBuf(end+1:newCap,1)         = single(nan);
            app.rawX(end+1:newCap,1:4)       = single(nan);
            app.rawY(end+1:newCap,1:4)       = single(nan);
            app.rawZ(end+1:newCap,1:4)       = single(nan);
            app.rawGndP(end+1:newCap,1:3)    = single(nan);
            app.rawGndEul(end+1:newCap,1:3)  = single(nan);
            app.bufCap = newCap;
        end
        function [Time_s, X, Y, Z, n] = finalizeProcessed(app)
            % Requiere Robotics Toolbox de Peter Corke (transl/trotx/troty/trotz).
            % Procesa TODO al final (offline), no en vivo por tick.

            Tlen   = app.wIdx;
            n      = app.numLiveCached;

            Time_s = app.tBuf(1:Tlen, 1);
            X      = nan(Tlen, n);
            Y      = nan(Tlen, n);
            Z      = nan(Tlen, n);

            % ===== ABSOLUTO: entregar buffers tal cual =====
            if ~app.useRelativeCached
                X(:,:) = app.rawX(1:Tlen, 1:n);
                Y(:,:) = app.rawY(1:Tlen, 1:n);
                Z(:,:) = app.rawZ(1:Tlen, 1:n);
                return;
            end

            % ===== RELATIVO (offline con HTMs) =====
            % Si no tienes euler por masa, se asumen ceros.
            hasEulPerMass = isprop(app,'rawEulX') && isprop(app,'rawEulY') && isprop(app,'rawEulZ') && ...
                ~isempty(app.rawEulX) && ~isempty(app.rawEulY) && ~isempty(app.rawEulZ) && ...
                size(app.rawEulX,2) >= n && size(app.rawEulY,2) >= n && size(app.rawEulZ,2) >= n;

            % mkT: construye T = transl * Rx * Ry * Rz con eulxyz (rad)
            mkT = @(p) transl(p(1:3).') * trotx(p(4)) * troty(p(5)) * trotz(p(6));

            for t = 1:Tlen
                % --- Ground pose [x y z eulX eulY eulZ] ---
                pG = app.rawGndP(t, :);
                eG = app.rawGndEul(t, :);
                if any(isnan(pG)) || any(isnan(eG))
                    % Si el GND no es válido en este tick, se dejan NaN
                    continue;
                end
                TG = mkT([pG, eG]);     % T_GND en marco global (Robotat)

                % --- Para cada masa k: T_Mk global y luego Trel = inv(TG)*TMk ---
                for k = 1:n
                    pk = [app.rawX(t,k), app.rawY(t,k), app.rawZ(t,k)];
                    if any(isnan(pk))
                        continue;
                    end
                    if hasEulPerMass
                        ek = [app.rawEulX(t,k), app.rawEulY(t,k), app.rawEulZ(t,k)];
                    else
                        ek = [0, 0, 0];
                    end

                    TMk  = mkT([pk, ek]);         % T_Mk (global)
                    Trel = TG \ TMk;         % Trel = inv(T_GND) * T_Mk
                    pr   = transl(Trel);           % [x y z] en marco GND

                    % Convención: invertir X (como tu versión previa)
                    X(t, k) = -pr(1);
                    Y(t, k) =  pr(2);
                    Z(t, k) =  pr(3);
                end
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if app.ModoDropDown.Value == "MQTT"
                system('python "mtlab test.py"')
            else
                try
                    app.robotat = robotat_connect();
                    uiwait(msgbox("Conectado a Robotat","Éxito","modal"));
                    disp('Conectado a Robotat');
                catch ME
                    app.robotat = [];
                    uialert(app.UIFigure, ME.message, 'Error al conectar');
                end
            end
            
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            % Reset de datos
            app.tData = [];

            %M1
            app.x1Data = []; app.y1Data = []; app.z1Data = [];

            %M2
            app.x2Data = []; app.y2Data = []; app.z2Data = [];

            % ==== M3 ====
            app.x3Data = []; app.y3Data = []; app.z3Data = [];

            % ==== M4 ====
            app.x4Data = []; app.y4Data = []; app.z4Data = [];


            % Lee y cachea parámetros (no se consultan UI en cada tick)
            if isprop(app,'NumMasses') && ~isempty(app.NumMasses)
                app.numLive = str2double(app.NumMasses.Value);
            else
                app.numLive = 4;
            end

            app.numLive = max(1, min(4, app.numLive));

            app.numLiveCached    = app.numLive;
            app.useRelativeCached = strcmpi(app.CoordenadasDropDown.Value,'Relativas');
            app.plotEnabled       = strcmpi(app.GraficarDropDown.Value,'Si');


            clearLiveGraphics(app);
            if app.plotEnabled
                createLiveWindow(app);
            end

            initRawBuffers(app, app.bufCap);

            app.t0 = tic;
            app.failCount = 0;

            % Timer: más rápido si no se grafica
            if ~isempty(app.tmr) && isvalid(app.tmr), stop(app.tmr); delete(app.tmr); end

            app.tmr = timer('ExecutionMode','fixedSpacing','Period',0.001,'BusyMode','drop', ...
                'StartDelay',0.001,'TimerFcn', @(~,~) updateOnce(app), ...
                'ErrorFcn', @(~,e) disp(['[Timer Error] ' getReport(e)]) );
            start(app.tmr);


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Detener y limpiar timer
            if ~isempty(app.tmr) && isvalid(app.tmr)
                stop(app.tmr);
                delete(app.tmr);
                app.tmr = [];
            end

            clearLiveGraphics(app);


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            Tlen = app.wIdx;
            if Tlen < 1
                uialert(app.UIFigure,'No hay datos para exportar.','Sin datos'); return;
            end

            [Time_s, X, Y, Z, n] = finalizeProcessed(app);

            T = table(Time_s);
            for i=1:n
                T.(['M' num2str(i) '_X']) = X(:,i);
                T.(['M' num2str(i) '_Y']) = Y(:,i);
                T.(['M' num2str(i) '_Z']) = Z(:,i);
            end

            % Nombre sugerido
            defaultName = sprintf('robotat_%d_masas_%s.csv', n, datestr(now,'yyyymmdd_HHMMSS'));

            % Diálogo guardar
            [file, path] = uiputfile('*.csv', 'Guardar datos como...', fullfile(app.lastSaveFolder, defaultName));
            if isequal(file,0)
                return;  % cancelado
            end

            fullfn = fullfile(path, file);

            % Guardar
            try
                writetable(T, fullfn, 'Delimiter', ',');
                app.lastSaveFolder = path;
                uialert(app.UIFigure, sprintf('Datos guardados en:\n%s', fullfn), 'Exportación exitosa');
            catch ME
                uialert(app.UIFigure, sprintf('No se pudo guardar el archivo:\n%s', ME.message), 'Error al exportar');
            end
        end

        % Value changed function: GraficarDropDown
        function GraficarDropDownValueChanged(app, event)
            app.plotEnabled = strcmpi(app.GraficarDropDown.Value, 'Si');

            % Si se apaga la graficación, cerrar ventana si existe
            if ~app.plotEnabled
                clearLiveGraphics(app);

            else
                % Si se enciende y hay grabación activa (timer corriendo), abre la ventana
                if ~isempty(app.tmr) && isvalid(app.tmr) && strcmp(app.tmr.Running,'on')
                    createLiveWindow(app);  % helper (más abajo)
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CoordenadasDropDownLabel = uilabel(app.UIFigure);
            app.CoordenadasDropDownLabel.Text = 'Coordenadas';
            app.CoordenadasDropDown = uidropdown(app.UIFigure);
            app.CoordenadasDropDown.Items = {'Absolutas', 'Relativas'};
            app.CoordenadasDropDown.Value = 'Absolutas';
            app.NmerodeMasasLabel = uilabel(app.UIFigure);
            app.NmerodeMasasLabel.Text = 'Número de Masas';
            app.NumMasses = uidropdown(app.UIFigure);
            app.NumMasses.Items = {'1', '2', '3', '4'};
            app.NumMasses.Value = '4';
            app.GraficarDropDownLabel = uilabel(app.UIFigure);
            app.GraficarDropDownLabel.Text = 'Graficar';
            app.GraficarDropDown = uidropdown(app.UIFigure);
            app.GraficarDropDown.Items = {'No', 'Si'};
            app.GraficarDropDown.ValueChangedFcn = createCallbackFcn(app, @GraficarDropDownValueChanged, true);
            app.GraficarDropDown.Value = 'No';
            app.ModoDropDownLabel = uilabel(app.UIFigure);
            app.ModoDropDownLabel.Text = 'Modo';
            app.ModoDropDown = uidropdown(app.UIFigure);
            app.ModoDropDown.Items = {'TCP', 'MQTT'};
            app.ModoDropDown.Value = 'TCP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end